classdef oceanus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ModeDropDownLabel              matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        RightPanel                     matlab.ui.container.Panel
        ReceiverPanel                  matlab.ui.container.Panel
        TransmitterPanel               matlab.ui.container.Panel
        DataEditFieldLabel             matlab.ui.control.Label
        DataEditField                  matlab.ui.control.EditField
        CarrierFrequencySelectorPanel  matlab.ui.container.Panel
        FrequencySliderHzLabel         matlab.ui.control.Label
        FrequencySliderHz              matlab.ui.control.Slider
        EditField                      matlab.ui.control.NumericEditField
        HzLabel                        matlab.ui.control.Label
        OutputFileNameEditFieldLabel   matlab.ui.control.Label
        OutputFileNameEditField        matlab.ui.control.EditField
        GenerateAudioButton            matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Menu                           matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        Menu2_2                        matlab.ui.container.Menu
        Menu3_2                        matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        ChooseOutputMenu               matlab.ui.container.Menu
        FileMenu                       matlab.ui.container.Menu
        AudioSinkMenu                  matlab.ui.container.Menu
        AdvancedSettingsMenu           matlab.ui.container.Menu
        Menu3                          matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        carrier_frequency =0.0;  % Hz
        quiet_time =0.0;         % seconds
        sampling_frequency =0.0; % Hz
        bits_per_sample =0.0;
        output_file_name ="";
        type_of_output =0; % 0 for file & 1 for audio sink
        output_file_type =0; %  0 for wav , 1 for 
        mode = 0; % 0 for TX &  1 for RX
    end
    
    properties (Access = private)
        DialogApp % Description
    end
    
    methods (Access = public)
        function []=c2toWave(app, iFile,oFile,f,toffset,oSps,BitsPerSample)
            % Convert a baseband signal (".c2" file format, complex envelope) to
            % broadband signal (".wav" file format).
            %
            % Author: Michel Barbeau, Carleton University
            % Version: July 8, 2019
            % iFile = name of ".c2" file (".c2" extension assumed)
            % oFile = output file name
        	% f = frequency vs time (in Hz)
            % t = time points
            % output is over a 120-second time interval, with 111-second frame
            % toffset = random prefix, in seconds, in [0,9]
            % oSps =  output file sampling frequency
            % upSampling = up sampling factor
            %
            %
            %%% read baseband signal from ".c2" file
            %
            fileID = fopen([iFile '.c2'],'r');
            % read file name (14 characters)
            fname=fread(fileID,[1 14],'char');
            % read WSPR type (integer) 2="2 minute mode"
            type=fread(fileID,[1 1],'int');
            % read frequency (double)
            dfreq=fread(fileID,[1 1],'double');
            % read data (float)
            buffer=fread(fileID,[2 45000],'float');
            % close the input file
            fclose(fileID);
            % in-phase
            idat=buffer(1,:);
            % quadrature
            qdat=buffer(2,:);
            %
            %%% up conversion
            %
            % up sampling factor
            upSampling = oSps / 375;
            % resample the baseband signal for the ".wav" format
            x=resample(complex(idat,qdat),upSampling,1);
            % number of time points, over a 120-second interval
            xl = oSps*120;
            % generate time points
            T = 120; % period (seconds)
            dt = T/xl; % sample period (second)
            t = 0:dt:T-dt;
            % modulation
            y = x.*exp(-1i*(2*pi*f.*t));
            % normalize in the [-1 1] range
            y = y/(max(abs(min(y)),max(y)));
            % add a random noise prefix during time offeset
            r=rand(1,round(toffset*oSps));
            y =[r(:);y];
            % write the wave file
            audiowrite(oFile,real(y),oSps,'BitsPerSample',BitsPerSample);
        end
        
        function [] = FrameGenerator(app)
            % Generate frames, coded in ".wav" files
            % Author: Michel Barbeau, Carleton University
            % Version: August 20, 2019
            %
            clear;
            %
            %%% PARAMETERS
            %
            % 1. Input file name (".c2" file format, complex envelope @ 375 samples/sec)
            iFile = '150613_1920';
            fprintf('Input file name: %s.c2\n', iFile)
            % 2. Carrier frequency
            f = 209; % Hz
            fprintf('Carrier frequency: %d Hz\n', f)
            % 3. Time offset (random quiet time prefix, in seconds, in [0,9])
            toffset = 0; % second
            fprintf('Time offset: %d sec.\n', toffset)
            % 4. Output file sampling frequency
            oSps = 4500; % samples/sec (a multiple of 375, e.g. 6K, 12K, 24K, 48K, 96K)
            fprintf('Output file sampling frequency: %d sps\n', oSps);
            % 5. Output file name (broadband signal in ".wav" format)
            oFile = int2str(f)+"_"+int2str(oSps)+".wav"; % use frequency number and sampling frequency
            fprintf('Output file name: %s\n', oFile);
            % 6. Bits per sample
            BitsPerSample = 16;
            %
            %%% CONVERSION
            %
            c2toWave(app, iFile, oFile, f, toffset, oSps, BitsPerSample);
        end
           
        function []= SetupControl(app)
            value = app.ModeDropDown.Value;
            if(value == "Transmitter")
                app.TransmitterPanel.Visible ='on';
                app.ReceiverPanel.Visible='off';
                app.ChooseOutputMenu.Visible = 'on';
                app.ChooseOutputMenu.Enable = 'on';
            else
                app.TransmitterPanel.Visible ='off';
                app.ReceiverPanel.Visible='on';
                app.ChooseOutputMenu.Visible='off';
                app.ChooseOutputMenu.Enable = 'off';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            SetupControl(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            t = 0:0.001:2;
            x = chirp(t,100,1,200,'quadratic');
            myAxe = app.UIAxes;
            [S,F,T] = spectrogram(x,128,120,128,1e3);
            imagesc(myAxe, F, T, log(abs(S'))); %plot the log spectrum
            set(myAxe,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom 
            axis square 
            axis tight 
            hold
            colorbar
        end

        % Value changed function: FrequencySliderHz
        function FrequencySliderHz_2ValueChanged(app, event)
            value = app.FrequencySliderHz.Value;
            app.EditField.Value = value;
            app.carrier_frequency = value;
        end

        % Value changing function: FrequencySliderHz
        function FrequencySliderHz_2ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = changingValue;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.FrequencySliderHz.Value= value;
            app.carrier_frequency = value;
        end

        % Menu selected function: AudioSinkMenu
        function AudioSinkMenuSelected(app, event)
                app.OutputFileNameEditField.Visible = false;
                app.OutputFileNameEditFieldLabel.Visible=false;
                app.type_of_output=1;
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
                app.OutputFileNameEditField.Visible = true;
                app.OutputFileNameEditFieldLabel.Visible=true;
                app.type_of_output=0;
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            SetupControl(app);
        end

        % Button pushed function: GenerateAudioButton
        function GenerateAudioButtonPushed(app, event)
            %Generate the .c2 file using wsprsim
            
        end

        % Menu selected function: AdvancedSettingsMenu
        function AdvancedSettingsMenuSelected(app, event)
            %disable this menu while frame generator dialog is open 
            app.AdvancedSettingsMenu.Enable = 'off'; 
            app.Button.Enable ='off';
            app.DialogApp = frame_generator_config();
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            app.AdvancedSettingsMenu.Enable = 'off';    
            app.Button.Enable ='off';
            app.DialogApp = frame_generator_config();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {543, 543};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {146, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModeDropDownLabel = uilabel(app.LeftPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.LeftPanel);
            app.ModeDropDown.Items = {'Transmitter', 'Receiver'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Transmitter';
            app.RightPanel = uipanel(app.GridLayout);
            app.ReceiverPanel = uipanel(app.RightPanel);
            app.ReceiverPanel.Title = 'Receiver';
            app.ReceiverPanel.Visible = 'off';
            app.TransmitterPanel = uipanel(app.RightPanel);
            app.TransmitterPanel.Title = 'Transmitter';
            app.DataEditFieldLabel = uilabel(app.TransmitterPanel);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.TransmitterPanel, 'text');
            app.CarrierFrequencySelectorPanel = uipanel(app.TransmitterPanel);
            app.CarrierFrequencySelectorPanel.Title = 'Carrier Frequency Selector';
            app.FrequencySliderHzLabel = uilabel(app.CarrierFrequencySelectorPanel);
            app.FrequencySliderHzLabel.Text = 'Frequency Slider (Hz)';
            app.FrequencySliderHz = uislider(app.CarrierFrequencySelectorPanel);
            app.FrequencySliderHz.Limits = [0 10000];
            app.FrequencySliderHz.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderHz_2ValueChanged, true);
            app.FrequencySliderHz.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderHz_2ValueChanging, true);
            app.EditField = uieditfield(app.CarrierFrequencySelectorPanel, 'numeric');
            app.EditField.Limits = [0 10000];
            app.EditField.ValueDisplayFormat = '%5.2f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 209;
            app.HzLabel = uilabel(app.CarrierFrequencySelectorPanel);
            app.HzLabel.Text = 'Hz';
            app.OutputFileNameEditFieldLabel = uilabel(app.TransmitterPanel);
            app.OutputFileNameEditFieldLabel.Text = 'Output File Name';
            app.OutputFileNameEditField = uieditfield(app.TransmitterPanel, 'text');
            app.GenerateAudioButton = uibutton(app.TransmitterPanel, 'push');
            app.GenerateAudioButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAudioButtonPushed, true);
            app.GenerateAudioButton.Text = 'Generate Audio';
            app.Button = uibutton(app.TransmitterPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Icon = 'gear3.png';
            app.Button.Text = '';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.Menu);
            app.Menu2_2.Text = 'Menu2';
            app.Menu3_2 = uimenu(app.Menu);
            app.Menu3_2.Text = 'Menu3';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ChooseOutputMenu = uimenu(app.SettingsMenu);
            app.ChooseOutputMenu.Visible = 'off';
            app.ChooseOutputMenu.Enable = 'off';
            app.ChooseOutputMenu.Text = 'Choose Output';
            app.FileMenu = uimenu(app.ChooseOutputMenu);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.AudioSinkMenu = uimenu(app.ChooseOutputMenu);
            app.AudioSinkMenu.MenuSelectedFcn = createCallbackFcn(app, @AudioSinkMenuSelected, true);
            app.AudioSinkMenu.Text = 'Audio Sink';
            app.AdvancedSettingsMenu = uimenu(app.SettingsMenu);
            app.AdvancedSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @AdvancedSettingsMenuSelected, true);
            app.AdvancedSettingsMenu.Text = 'Advanced Settings';
            app.Menu3 = uimenu(app.UIFigure);
            app.Menu3.Text = 'Menu3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oceanus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end